classdef IPPR_Glove_Defect_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        StartTab                       matlab.ui.container.Tab
        GloveDefectDetectionSystemLabel  matlab.ui.control.Label
        ExitApplicationButton          matlab.ui.control.Button
        UploadImageButton              matlab.ui.control.Button
        PreProcessingTab               matlab.ui.container.Tab
        ProceedtoSegmentationButton    matlab.ui.control.Button
        BinariseCheckBox               matlab.ui.control.CheckBox
        Label_3                        matlab.ui.control.Label
        GaussianBlurCheckBox           matlab.ui.control.CheckBox
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ConverttoGrayscaleCheckBox     matlab.ui.control.CheckBox
        PreProcessingStepsLabel        matlab.ui.control.Label
        PreProcessingPanel             matlab.ui.container.Panel
        Segmentation                   matlab.ui.container.Tab
        ProceedtoClassificationButton  matlab.ui.control.Button
        SegmentationPanel              matlab.ui.container.Panel
        ClassificationTab              matlab.ui.container.Tab
        ClassificationPanel            matlab.ui.container.Panel
    end

    
    properties (Access = private)
        uploadedImagesFileName % Uploaded Images File Names
        uploadedImagesPath % Uploaded Images Path
        subplotRow % Number of rows in panel subplot
        subplotCol % Number of columns in panel subplot
        maskImage % Image with No Defects
        segmentStats % regionprops for segments
        segment
        processedImg % processed Images
        maskStats % Description
        segmentedImages
     end

    
    methods (Access = private)
        
        function img = ImgResizeGrayscale(~, imgPath)
            img = imread(imgPath);
            
            % Convert to Grayscale
            img = im2gray(img);

            % Resize image
            img = imresize(img, [480, 480]);
        
        end

        function img = ImgGaussianBlur(app, imgPath)
            img = ImgResizeGrayscale(app, imgPath);
            
            % Gaussian Blur
            img = imgaussfilt(img, 3);        
        end

        function img = ImgBinarise(app, imgPath)
           imgBlur = ImgGaussianBlur(app, imgPath);
            
            % Otsu's Method
            counts = imhist(imgBlur,10);
            thresholdValue = otsuthresh(counts);

            % Convert to Binary
            img = imbinarize(imgBlur, thresholdValue);            
        end

        function img = ImgPreProcess(app, imgPath)                       
            
            img = ImgBinarise(app, imgPath);
        end

        function img = ImgSegmentation(app, imgPath)
%             img = imread(imgPath);

            img = ImgPreProcess(app, imgPath);
%             img = uint8(img);
%             img = imsegkmeans(img, 1);

%             [labeledImage, numberOfRegions] = bwlabel(img);
%             props = regionprops(labeledImage, 'MajorAxisLength', 'Orientation');

              B = bwboundaries(img);

%             [B,L] = bwboundaries(img,'noholes');
%             imshow(label2rgb(L, @jet, [.5 .5 .5]))
%             hold on
%             for k = 1:length(B)
%                boundary = B{k};
%                plot(boundary(:,2), boundary(:,1), 'w', 'LineWidth', 2)
%             end

        end

        function segment = ImgSegment(app, img, mask)
            % Preprocess image
%             img = ImgPreProcess(app, imgPath);
%             
            % Morphology structure
            struc = strel('line',10,45);

            % Image Statistics
            imgStats = regionprops(img, "all");
            imgArea = imgStats.Area;
            maskArea = app.maskStats.Area;

            % Determine image segmentation method based on area
            if (imgArea > maskArea)
                % Deformed/Loose Threads
                segment = imerode(img, struc);
                segment(mask) = 0;
            else
                % Missing Finger/Rip/Perforation/Scratch/Tear/Hole/Stain
                segment = imerode(mask, struc);
                segment(img) = 0;
            end
        end

        function img = RetrieveMaskImage(~, path, imgPath)
            [ ~, name, ~] = fileparts(imgPath);
            % Retrieve template image based on folder name
            if (contains(name, "Medical"))
                img =fullfile(path, "Medical-NoDefects.jpg");
            elseif (contains(name, "Rubber"))
                img = fullfile(path, "Rubber-NoDefects.jpg");
            elseif (contains(name, "Cloth"))
                img =fullfile(path, "Cloth-NoDefects.jpg");
            end
        end
        
        
        function img = ImgOrientate(~, maskImg, oriImg)
            orientation = 0;
            prevSimilarity = 0;

            for i = 0:90:270
                rotateImg =imrotate(oriImg, i);
                similarity = ssim(uint8(rotateImg), uint8(maskImg));

                if (similarity > prevSimilarity)
                    prevSimilarity = similarity;
                    orientation = i;
                end
            end
    
            img = imrotate(oriImg, orientation);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            
            % Get Images
            [file,path] = uigetfile("*.png; *.jpg; *.jpeg; *.tif; *.gif", "Image Files", "MultiSelect","on");
            file = cellstr(file);
            if length(file) > 1
                app.maskImage = RetrieveMaskImage(app,path,file{1});

                for i = 1:length(file)
                    x = fullfile(path, file{i});
                    app.uploadedImagesPath{i} = x;
                    app.uploadedImagesFileName{i} = file{i};
%                     disp(x);
%                     img = imread(x);
%                     figure(i);
%                     imshow(img);
                end
            elseif length(file) == 1
                    x = fullfile(path, file{1});
                    app.uploadedImagesPath{1} = x;
                   app.uploadedImagesFileName{1} = file{1};
                   app.maskImage = RetrieveMaskImage(app,path, file{1});
            else
                message("Error, no file selected");
                disp("Error");
            end
            
            app.subplotRow = ceil(length(app.uploadedImagesPath) / 3);
            
            if (length(app.uploadedImagesPath) == 1)
                app.subplotCol = 1;
            elseif(length(app.uploadedImagesPath) == 2)
                app.subplotCol = 2;
            else
                app.subplotCol = 3;
            end

            delete(f); %delete the dummy figure

            % Change tabs
            app.TabGroup.SelectedTab = app.PreProcessingTab;
            app.TabGroupSelectionChanged();

%             files = fullfile(path, file);
%             x = imread(files);
%             imshow(x);
%            disp("Path ", path);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Start Page - clear values
            if strcmp(app.TabGroup.SelectedTab.Title, app.StartTab.Title)
                app.uploadedImagesFileName(:) = [];
                app.uploadedImagesPath(:) = [];
                app.subplotRow = 0;
                app.subplotCol = 0;
                app.maskImage(:) = []; % File path to Image with No Defects
                app.segmentStats(:) = []; % regionprops for segments
                app.segment(:) = [];
                app.processedImg(:) = []; % processed Images
                app.maskStats(:) = []; % Description
                app.segmentedImages(:) = [];
                clc;               
            end

            % Preprocessing
            if strcmp(app.TabGroup.SelectedTab.Title, app.PreProcessingTab.Title)
                
                % Show loading bar
                fig = uifigure(WindowStyle="modal", Position=[300,350,405,100], Name="Loading...");
                loader = uiprogressdlg(fig,'Title','Processing Images...', 'Indeterminate','on');
                drawnow;
           
                 % Clear panel
                delete(app.PreProcessingPanel);
                app.PreProcessingPanel = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.PreProcessingTab);
             
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgPreProcess(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, "Parent",ax, "InitialMagnification", "fit"); 
                end
               
                app.ConverttoGrayscaleCheckBox.Value = 1;
                app.GaussianBlurCheckBox.Value = 1;
                app.BinariseCheckBox.Value = 1;

                % Close Loader
                close(loader);
                close(fig);
            end 
            
            % Segmentation
            if strcmp(app.TabGroup.SelectedTab.Title, app.Segmentation.Title)
                % Clear Panel
                delete(app.SegmentationPanel);
                app.SegmentationPanel = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.Segmentation);

                % Show loading bar
                fig = uifigure(WindowStyle="modal", Position=[300,350,405,100], Name="Loading...");
                loader = uiprogressdlg(fig,'Title','Processing Images...', 'Indeterminate','on');
                drawnow;

                % Process Mask
                app.maskImage = ImgPreProcess(app, app.maskImage);
                app.maskStats = regionprops(app.maskImage, "all");
                    
                % Segment Images
                for i = 1:length(app.uploadedImagesPath)
                    preprocessedImg = ImgPreProcess(app, app.uploadedImagesPath{i});
                    preprocessedImg = ImgOrientate(app, app.maskImage, preprocessedImg);
                    segmentedImage = ImgSegment(app, preprocessedImg, app.maskImage);
                    app.segmentedImages{i} = segmentedImage;
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.SegmentationPanel);
%                     imshow(segmentedImage, 'Parent', ax);


                    % Identifying connected components
                    imgLabel = bwconncomp(segmentedImage, 4);
                    ccNum = imgLabel.NumObjects;
          
                    imgStats = regionprops(imgLabel, 'all');
                    imgBox = zeros(ccNum, 4);

                    % Identifying segments
%                     maskPxList = app.maskStats.PixelList;
%                     app.segment = zeros(ccNum,2);

                    for k = 1 : ccNum
                        area = imgStats(k).Area;
                        if (area < 50)
                            continue;
                        end

                        app.segment(length(app.segment)+1,:) = [i,k];
                        imgBox(k,:) = [imgStats(k).BoundingBox];     

%                         imgPxList = imgStats(k).PixelList;
%                         xq = imgPxList(:,1);
%                         yq = imgPxList(:,2);

                        % Determine if CC is within the Mask
%                         [in, on] = inpolygon(xq, yq, maskPxList(:,1), maskPxList(:,2));
                              
%                         if (numel(xq(~in)) > 10 || numel(xq(on))> 75)
%                             app.segment(length(app.segment)+1,:) = [i,k];
%                             imgBox(k,:) = [imgStats(k).BoundingBox];                            
%                         elseif (numel(xq(in))> 10)
%                             app.segment(length(app.segment)+1,:) = [i,k];
%                             imgBox(k,:) = [imgStats(k).BoundingBox];
%                         end
                    end
                  

                    % Shrink array
%                     app.segment  = nonzeros(app.segment);                    
%                     disp(segment);

                    % Display connected components                   
                    imshow(preprocessedImg, 'Parent', ax);
                    hold(ax, "on");
                    for j = 1:ccNum
                        rectangle(ax, 'position',imgBox(j,:), EdgeColor='r', LineWidth=1);
                    end
                    hold(ax, "off");


                end
                % close the progress bar
                close(loader);
                close(fig);
%                 delete(app.Segmentation);
%                 app.Segmentation = uipanel("AutoResizeChildren", "off", Title="", Position=[242,1,627,563], Parent=app.Segmentation);
% 
%                 for i = 1:length(app.uploadedImagesPath)
%                     img = ImgPreProcess(app, app.uploadedImagesPath{i});
% %                     img = ImgSegmentation(app, app.uploadedImagesPath{i});
% %                     img = ImgSegmentation(app, app.uploadedImagesPath{i});
%                     
% %                     img = bwboundaries(img);
%                     
% %                     se = strel('disk', 5);
% %                     se2 = strel('line', 10, 45);
% %                     img = imclose(img, se);
% %                     img = imclose(img, se2);
%                     
% %                     [B,L] = bwboundaries(img);
% % 
%                     ax = subplot(app.subplotRow, 3, i, 'Parent', app.Segmentation);
% %                     ax2 = subplot(2, length(app.uploadedImagesPath), i+length(app.uploadedImagesPath), 'Parent', app.Segmentation);
% %                     ax = subplot(app.subplotRow, 3, i, 'Parent', app.Segmentation);
% % %                     imshow(img, 'Parent', ax);
% % 
% %                     imshow(label2rgb(L, @jet, [.5 .5 .5]), 'Parent', ax)
% % %                     hold on
% % %                     for k = 1:length(B)
% % %                        boundary = B{k};
% % %                        plot(boundary(:,2), boundary(:,1), 'w', 'LineWidth', 2)
% % %                     end
% 
%                     hole = xor(img,imfill(img,'holes'));
% %                     img = double(img);
% %                     img(hole) = 2;
% %                     x = label2rgb(img);
% %                     disp(x);
% %                     img = img(hole);
% %                     hole = xor(img,imfill(img,'holes'));
% %                     img = double(img);
% %                     img(hole) = 1;
% %                     imshow(label2rgb(img), 'Parent', ax);
% %                      
%                      imshow(hole, 'Parent', ax);
% %                      disp(regionprops(hole, 'Area'));
% % 
% % %                      [c, h] = imcontours(hole);
% % %                      approx = 
% % %                     imshow(bwboundaries(uint8(hole)), 'Parent', ax);
% % 
% % %                     approxCurve = cv.approxPolyDP(curve);
% % %                     disp(approxCurve);
% % 
% % %                     hole = smooth2a(imagesc(hole),10,10);
% % %                      imshow((hole), 'Parent', ax);
% %                     
% %                     % Extract the perimeter of the segment
% %                     perim = bwperim(hole);
% %                     
% %                     % Define a smoothing filter
% %                     smoothingFilter = fspecial('gaussian', [5 5], 2);
% %                     
% %                     % Smooth the perimeter
% %                     smoothPerim = imfilter(double(perim), smoothingFilter);
% %                     
% %                     % Convert the smoothed perimeter back to binary
% %                     smoothBinaryImage = imbinarize(smoothPerim);
% %                     imshow(smoothBinaryImage, 'Parent', ax2);
% 
%                 end
%                 img  = app.uploadedImagesPath;
%                 seg = imsegkmeans(img, 3);
% %                 mshow(img, 'Parent', ax);

            end

            % Classification
            if strcmp(app.TabGroup.SelectedTab.Title, app.ClassificationTab.Title)
                % Show loading bar
                fig = uifigure(WindowStyle="modal", Position=[300,350,405,100], Name="Loading...");
                loader = uiprogressdlg(fig,'Title','Processing Images...', 'Indeterminate','on');
                drawnow;

                % Clear Panel
                delete(app.ClassificationPanel);
                app.ClassificationPanel = uipanel("AutoResizeChildren", "off", Title="", Position=[0,1,869,563], Parent=app.ClassificationTab);
                
                % Mask Stats
                maskArea = app.maskStats.Area;
                maskMajorAxis = app.maskStats.MajorAxisLength;
                axisThreshold = maskMajorAxis - 5;

                for index = 1 : length(app.uploadedImagesPath)
                    ax = subplot(app.subplotRow, app.subplotCol, index, 'Parent', app.ClassificationPanel);

                    preprocessedImg = ImgPreProcess(app, app.uploadedImagesPath{index});
                    preprocessedImg = ImgOrientate(app, app.maskImage, preprocessedImg);
                    imgMainStats = regionprops(preprocessedImg, "all");
                    imgMajorArea = imgMainStats.Area;
                    imgMajorAxis = imgMainStats.MajorAxisLength;
                    
%                     segmentedImage = ImgSegment(app, app.uploadedImagesPath{1}, maskImg);
                    segmentedImage = app.segmentedImages{index};

                    imgLabel = bwconncomp(segmentedImage, 4);
                    imgStats = regionprops(imgLabel, 'all');
                    ccNum = imgLabel.NumObjects;

                    % Classify Defects
                    imshow(preprocessedImg, "Parent",ax); 
                    hold(ax, "on");

                    % Reset to empty
                    imgSegmentIdx = [];
                    imgSegment =[];

                    imgSegmentIdx = (app.segment(:,1)==index);                           
                    imgSegment = app.segment(imgSegmentIdx,:);
%                     disp("APP.segment");
%                     disp(app.segment);
%                     disp("SEGMENT");
%                     disp(imgSegment);
%                     disp("LENGTH");
%                     disp(numel(imgSegment(:,1)));

                    holesCount = 0;
                    for i = 1:numel(imgSegment(:,1))
%                         disp("I:"+i);

                        coords = imgStats(imgSegment(i,2)).BoundingBox;
                        area = imgStats(imgSegment(i,2)).Area;

                        extent = imgStats(imgSegment(i,2)).Extent;
                        circularity = imgStats(imgSegment(i,2)).Circularity;
                        convexHull = imgStats(imgSegment(i,2)).ConvexHull;

                        if (imgMajorArea > maskArea)
                            if (area >= 50 && area <= 250) % Loose Thread
                                rectangle(ax, 'position', coords, EdgeColor='r');
                                text(ax, coords(1),coords(2)-10, "Loose Thread",'BackgroundColor','g');
                            elseif (area > 250) % Deformed
                                rectangle(ax, 'position', coords, EdgeColor='r');
                                text(ax, coords(1),coords(2)-10, "Deformed",'BackgroundColor','g');
                            end
                        else
                            % Hole/Tear/Perforation/Rip/Stain/Scratch
%                             if (ccNum > 1)
%                                     rectangle(ax, 'position', coords, EdgeColor='r');
%                                     disp("i = "+i);
%                                     disp("round(numel(imgSegment(:,1))) = "+ round(numel(imgSegment(:,1))));
%                                 if (i == round(numel(imgSegment(:,1))))
%                                     text(ax, coords(1),coords(2)-10, "Perforation",'BackgroundColor','g');
%                                 end
%                             else
                                if (axisThreshold < imgMajorAxis)
    
                                    plot(ax, convexHull(:,1), convexHull(:,2),Color="r", LineWidth=2);
    
                                    if (circularity >= 1 && extent <= 0.8)
%                                         text(ax, coords(1),coords(2)-10, "Stain",'BackgroundColor','g');
                                        holesCount = holesCount + 1;
                                    elseif(extent <= 0.75 && (circularity > 0.6 && circularity < 0.9))
%                                         text(ax, coords(1),coords(2)-10, "Hole",'BackgroundColor','g');
                                        holesCount = holesCount + 1;
                                    elseif(circularity <= 0.6)
                                        if (area <= 1000)
                                            text(ax, coords(1),coords(2)-10*app.subplotCol, "Scratch",'BackgroundColor','g');
                                        elseif (area > 1000 && area <= 3500)
                                            text(ax, coords(1),coords(2)-10, "Tear",'BackgroundColor','g');
                                        else
                                            text(ax, coords(1),coords(2)-10, "Rip",'BackgroundColor','g');
                                        end
                                    else
                                        text(ax, coords(1),coords(2)-10, "Defect",'BackgroundColor','g');
                                    end
    
                                else % Missing Finger
                                    if (area > 250)
                                        rectangle(ax, 'position', coords, EdgeColor='r');
                                        text(ax, coords(1),coords(2)-10, "Missing Finger",'BackgroundColor','g');
                                    end
                                end
%                             end
                        end


                    end

                    for i = 1:numel(imgSegment(:,1))
                        disp("I:"+i);

                        coords = imgStats(imgSegment(i,2)).BoundingBox;
                        extent = imgStats(imgSegment(i,2)).Extent;
                        circularity = imgStats(imgSegment(i,2)).Circularity;
                        
                        if (holesCount == 1)
                            if (circularity >= 1 && extent <= 0.8)
                                text(ax, coords(1),coords(2)-10, "Stain",'BackgroundColor','g');
                            elseif(extent <= 0.75 && (circularity > 0.6 && circularity < 0.9))
                                text(ax, coords(1),coords(2)-10, "Hole",'BackgroundColor','g');
                            end
                        elseif (holesCount > 1)
                            if (i == round(numel(imgSegment(:,1))))
                                text(ax, coords(1),coords(2)-10, "Perforation",'BackgroundColor','g');
                            end
                        end
                    end

                    hold(ax, "off");
                end

                % Close loader
                close(loader);
                close(fig);
            end
        end

        % Value changed function: ConverttoGrayscaleCheckBox
        function ConverttoGrayscaleCheckBoxValueChanged(app, event)
            value = app.ConverttoGrayscaleCheckBox.Value;
%             subplotRow = ceil(length(app.uploadedImagesPath) / 3);
            
            app.GaussianBlurCheckBox.Value = 0;
            app.BinariseCheckBox.Value = 0;

            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgResizeGrayscale(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = app.uploadedImagesPath{i};
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end 
        end

        % Value changed function: GaussianBlurCheckBox
        function GaussianBlurCheckBoxValueChanged(app, event)
            value = app.GaussianBlurCheckBox.Value;
            
            app.BinariseCheckBox.Value = 0;
            app.ConverttoGrayscaleCheckBox.Value = 1;
            
            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgGaussianBlur(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgResizeGrayscale(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end             
        end

        % Value changed function: BinariseCheckBox
        function BinariseCheckBoxValueChanged(app, event)
            value = app.BinariseCheckBox.Value;

            app.ConverttoGrayscaleCheckBox.Value = 1;
            app.GaussianBlurCheckBox.Value = 1;

            if (value) == 1
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgBinarise(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            else
                for i = 1:length(app.uploadedImagesPath)
                    img = ImgGaussianBlur(app, app.uploadedImagesPath{i});
                    ax = subplot(app.subplotRow, app.subplotCol, i, 'Parent', app.PreProcessingPanel);
                    imshow(img, 'Parent', ax);
                end
            end           
        end

        % Button pushed function: ExitApplicationButton
        function ExitApplicationButtonPushed(app, event)
           delete(app);
        end

        % Button pushed function: ProceedtoSegmentationButton
        function ProceedtoSegmentationButtonPushed(app, event)
            % Change tabs
            app.TabGroup.SelectedTab = app.Segmentation;
            app.TabGroupSelectionChanged();
        end

        % Button pushed function: ProceedtoClassificationButton
        function ProceedtoClassificationButtonPushed(app, event)
             % Change tabs
            app.TabGroup.SelectedTab = app.ClassificationTab;
            app.TabGroupSelectionChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = 'Start';
            app.UploadImageButton = uibutton(app.StartTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.ExitApplicationButton = uibutton(app.StartTab, 'push');
            app.ExitApplicationButton.ButtonPushedFcn = createCallbackFcn(app, @ExitApplicationButtonPushed, true);
            app.ExitApplicationButton.Text = 'Exit Application';
            app.GloveDefectDetectionSystemLabel = uilabel(app.StartTab);
            app.GloveDefectDetectionSystemLabel.Text = 'Glove Defect Detection System';
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PreProcessingPanel = uipanel(app.PreProcessingTab);
            app.PreProcessingStepsLabel = uilabel(app.PreProcessingTab);
            app.PreProcessingStepsLabel.Text = 'Pre-Processing Steps';
            app.ConverttoGrayscaleCheckBox = uicheckbox(app.PreProcessingTab);
            app.ConverttoGrayscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConverttoGrayscaleCheckBoxValueChanged, true);
            app.ConverttoGrayscaleCheckBox.Text = 'Convert to Grayscale';
            app.ConverttoGrayscaleCheckBox.Value = true;
            app.Label = uilabel(app.PreProcessingTab);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.PreProcessingTab);
            app.Label_2.Text = '2.';
            app.GaussianBlurCheckBox = uicheckbox(app.PreProcessingTab);
            app.GaussianBlurCheckBox.ValueChangedFcn = createCallbackFcn(app, @GaussianBlurCheckBoxValueChanged, true);
            app.GaussianBlurCheckBox.Text = 'Gaussian Blur';
            app.GaussianBlurCheckBox.Value = true;
            app.Label_3 = uilabel(app.PreProcessingTab);
            app.Label_3.Text = '3.';
            app.BinariseCheckBox = uicheckbox(app.PreProcessingTab);
            app.BinariseCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinariseCheckBoxValueChanged, true);
            app.BinariseCheckBox.Text = 'Binarise';
            app.BinariseCheckBox.Value = true;
            app.ProceedtoSegmentationButton = uibutton(app.PreProcessingTab, 'push');
            app.ProceedtoSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoSegmentationButtonPushed, true);
            app.ProceedtoSegmentationButton.Text = 'Proceed to Segmentation';
            app.Segmentation = uitab(app.TabGroup);
            app.Segmentation.Title = 'Segmentation';
            app.SegmentationPanel = uipanel(app.Segmentation);
            app.ProceedtoClassificationButton = uibutton(app.Segmentation, 'push');
            app.ProceedtoClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedtoClassificationButtonPushed, true);
            app.ProceedtoClassificationButton.Text = 'Proceed to Classification';
            app.ClassificationTab = uitab(app.TabGroup);
            app.ClassificationTab.Title = 'Classification';
            app.ClassificationPanel = uipanel(app.ClassificationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPPR_Glove_Defect_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end